classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Label2            matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_8           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        EditField_4Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        EditField_5Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.EditField
        EditField_7Label  matlab.ui.control.Label
        EditField_7       matlab.ui.control.NumericEditField
        EditField_8Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.NumericEditField
        EditField_9Label  matlab.ui.control.Label
        EditField_9       matlab.ui.control.NumericEditField
        Label3            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            s1=app.EditField_7.Value;
            s2=app.EditField_8.Value;
            s3=app.EditField_9.Value;
            s4=s1+s2+s3;
            app.Label2.Text=num2str(s4);
            s5=app.Label_7.Text;
            if s4>=270
                app.Label3.Text='Вы проходите по конкурсу';             
            end
            if s4<270
                app.Label3.Text='Вы не прошли';             
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Фамилия, Имя, Отчество'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Предмет'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'Баллы ЕГЭ'; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'Ваш балл:'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'Проходной балл: 270'; ''};
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Вычислить'; ''};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Имя';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Фамилия';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Отчество ';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'Предмет(1)';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'Предмет(2)';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'Предмет(3)';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = 'Балл(1)';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = 'Балл(2)';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = 'Балл(3)';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end