classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UIAxes        matlab.ui.control.UIAxes
        SinxCheckBox  matlab.ui.control.CheckBox
        CosxCheckBox  matlab.ui.control.CheckBox
        Button        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.SinxCheckBox.Value==1                
                x = linspace(0,4*pi,500);
                y = sin(x);
                plot(app.UIAxes,x,y);
                hold(app.UIAxes,'on');
            end
            if app.CosxCheckBox.Value==1
                x = linspace(0,4*pi,500);
                y = cos(x);
                plot(app.UIAxes,x,y);
                hold(app.UIAxes,'on');
            end
            hold(app.UIAxes,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SinxCheckBox = uicheckbox(app.UIFigure);
            app.SinxCheckBox.Text = 'Sin(x)';
            app.CosxCheckBox = uicheckbox(app.UIFigure);
            app.CosxCheckBox.Text = 'Cos(x)';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Построить'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end